classdef app1_converted2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        RightPanel         matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
        LeftPanel          matlab.ui.container.Panel
        TextArea           matlab.ui.control.TextArea
        UploadSoundButton  matlab.ui.control.Button
        SelectSwitch       matlab.ui.control.Switch
        SelectSwitchLabel  matlab.ui.control.Label
        PlotgraphButton    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadSoundButton
        function UploadSoundButtonPushed(app, event)
            [filename ,path]=uigetfile('*.mp3');
            app.TextArea.Value=filename;
            figure(app.UIFigure);
            rawdata=load(filename);
            disp(rawdata);
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            value = rawdata
        end

        % Callback function: PlotgraphButton, UIAxes
        function PlotgraphButtonPushed(app, event)
          [X, Fs] = audioread('G4.mp3');
          X = X(:,1);
          N = numel(X);
          T  = 1/Fs;      % [s] sampling period       
          deltaF = Fs/N;  % [1/min]) frequency intervalue of discrete signal
            
          % compute the fast fourier transform
          Y = fft(X);
          % manually shifting the FFT
          Y = abs(Y/N);
          Amp = [Y(ceil(end/2)+1:end)' Y(1) Y(2:ceil(end/2))']';
          if (mod(N,2) == 0)
          sampleIndex = -N/2:1:N/2-1; %raw index for FFT plot
          else
          sampleIndex = -(N-1)/2:1:(N-1)/2; %raw index for FFT plot
          end
          AmpSquared = Amp.^2;
          plot(app.UIAxes,deltaF*sampleIndex, AmpSquared);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.PlotgraphButton = uibutton(app.LeftPanel, 'push');
            app.PlotgraphButton.ButtonPushedFcn = createCallbackFcn(app, @PlotgraphButtonPushed, true);
            app.PlotgraphButton.Text = 'Plot graph';
            app.SelectSwitchLabel = uilabel(app.LeftPanel);
            app.SelectSwitchLabel.Text = 'Select';
            app.SelectSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SelectSwitch.Items = {'mic', 'file'};
            app.SelectSwitch.Value = 'mic';
            app.UploadSoundButton = uibutton(app.LeftPanel, 'push');
            app.UploadSoundButton.ButtonPushedFcn = createCallbackFcn(app, @UploadSoundButtonPushed, true);
            app.UploadSoundButton.Text = 'Upload Sound';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Power of FFT of Wave (Audible Range = 20-20\times10^4 Hz)')
            xlabel(app.UIAxes, 'Frequency [Hz]')
            ylabel(app.UIAxes, '|FFT(Wave)|^2')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0'; '200'; '400'; '600'; '800'; '1000'; '1200'; '1400'; '1600'; '1800'; '2000'};
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.8'; '1'; '1.2'; '1.4'; '1.6'; '1.8'; '2.0'; '2.2'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @PlotgraphButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_converted2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end